classdef PlotLongData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        OutputMenu                    matlab.ui.container.Menu
        SaveasMenu                    matlab.ui.container.Menu
        ExportasPNGMenu               matlab.ui.container.Menu
        SettingsMenu                  matlab.ui.container.Menu
        AxissettingsMenu              matlab.ui.container.Menu
        ColorMenu                     matlab.ui.container.Menu
        PlottypeButtonGroup           matlab.ui.container.ButtonGroup
        LineButton                    matlab.ui.control.RadioButton
        BoxplotButton                 matlab.ui.control.RadioButton
        ROIinmainfigureDropDownLabel  matlab.ui.control.Label
        ROIinmainfigureDropDown       matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        statsObj % StatManager object containing ROI data.
        mainFig % Handle to the main figure.
        summaryFig % Handle to Summary figure.
        axHandleList % List of axis handles from summaryFig.
    end
    
    methods (Access = private)
        
        function createPlots(app)
            % This method populates the Main and Summary Figures with the
            % data stored in StatsObj.
            disp('Creating plots...')
            % First populate the summary figure with all plots:
            [~, indx_headers] = ismember({'observationID', 'data', 'AcquisitionIndx', 'dataSize'}, app.statsObj.stats_data_headers);
            
            for i = 1:numel(app.statsObj.obs_list)
                % Create axis:
                app.axHandleList(i) = subplot(1,numel(app.statsObj.obs_list),i,'replace','Parent', app.summaryFig);
                % Get data from current observation:                
                indx_obs_list = cellfun(@(x) find(strcmp(app.statsObj.obs_list{i}, x)),...
                    app.statsObj.stats_data(:,indx_headers(1)), 'UniformOutput',false);                
                b_missObs = cellfun(@isempty, indx_obs_list); % row containing the current observation.
                data = cellfun(@(x,y) x(y), app.statsObj.stats_data(:,indx_headers(2)), indx_obs_list); 
                xList = repmat(app.statsObj.stats_data(:,indx_headers(3)))
                cellfun(@(x,y,z) repmat(x,size(z)), app.statsObj.stats_data(:,indx_headers(3)), indx_obs_list,...
                    data, 'UniformOutput',false); % Acquisition index prepped for scatter plot.
                % Remove items missing the current observation:
                data(b_missObs) = [];
                xList(b_missObs)= [];
                % Create scatter plots:
%                 scatter(app.axHandleList(i), xList, )


            end
            
        end


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% CUSTOM CALLBACKS %%%%%%%%%%%%%%%%%%%%%%
        function DoNothing(app,~,~)
            % EMPTY!%
            
        end


         %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% %%%%%%
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, statsObj)
            
            %
            app.statsObj = statsObj;
            % Populate dropdown list:
            app.ROIinmainfigureDropDown.Items = app.statsObj.obs_list;
            % Create figures:
            app.mainFig = figure('Name', ['Main - ' app.ROIinmainfigureDropDown.Value], 'Position', [0 0 650 500], 'CloseRequestFcn', @app.DoNothing);
            app.summaryFig = figure('Name', 'Summary Window', 'Position', [0 0 500 600], 'CloseRequestFcn', @app.DoNothing, 'Scrollable',"on");            
            % Create plots:
            app.createPlots;           
            % Show Figures and app:
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.mainFig)
            delete(app.summaryFig)
            delete(app)            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OutputMenu = uimenu(app.UIFigure);
            app.OutputMenu.Text = 'Output';
            app.SaveasMenu = uimenu(app.OutputMenu);
            app.SaveasMenu.Text = 'Save as...';
            app.ExportasPNGMenu = uimenu(app.OutputMenu);
            app.ExportasPNGMenu.Text = 'Export as.PNG';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.AxissettingsMenu = uimenu(app.SettingsMenu);
            app.AxissettingsMenu.Text = 'Axis settings';
            app.ColorMenu = uimenu(app.SettingsMenu);
            app.ColorMenu.Text = 'Color';
            app.PlottypeButtonGroup = uibuttongroup(app.UIFigure);
            app.PlottypeButtonGroup.Title = 'Plot type';
            app.LineButton = uiradiobutton(app.PlottypeButtonGroup);
            app.LineButton.Text = 'Line';
            app.LineButton.Value = true;
            app.BoxplotButton = uiradiobutton(app.PlottypeButtonGroup);
            app.BoxplotButton.Text = 'Boxplot';
            app.ROIinmainfigureDropDownLabel = uilabel(app.UIFigure);
            app.ROIinmainfigureDropDownLabel.Text = 'ROI in main figure';
            app.ROIinmainfigureDropDown = uidropdown(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotLongData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end